classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        L1mEditFieldLabel    matlab.ui.control.Label
        L1mEditField         matlab.ui.control.NumericEditField
        L2mEditFieldLabel    matlab.ui.control.Label
        L2mEditField         matlab.ui.control.NumericEditField
        M1kgEditFieldLabel   matlab.ui.control.Label
        M1kgEditField        matlab.ui.control.NumericEditField
        M2kgEditFieldLabel   matlab.ui.control.Label
        M2kgEditField        matlab.ui.control.NumericEditField
        RunsimulationButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            g=9.8;
            l1=app.L1mEditField.Value;
            l2=app.L2mEditField.Value;
            m1=app.M1kgEditField.Value;
            m2=app.M2kgEditField.Value;
            
            mdl="model";
            open_system(mdl);
            
            in = Simulink.SimulationInput("model");
            
            in = in.setVariable("l1",l1);
            in = in.setVariable("l2",l2);
            in = in.setVariable("m1",m1);
            in = in.setVariable("m2",m2);
            
            simout=sim(in);
            t=simout.x.time
            i=find(t==10);
            x=simout.x.data
            y=simout.y.data
            x0=x(i)
            y0=y(i)
            plot(app.UIAxes,simout.x.data,simout.y.data,"g",x0,y0,'b*');
            plot(app.UIAxes2,simout.x.time,simout.t1.data,"b",simout.x.time,simout.t2.data,"g");
            legend(app.UIAxes2,"theta 1", "theta 2")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.L1mEditFieldLabel = uilabel(app.UIFigure);
            app.L1mEditFieldLabel.Text = 'L1(m)';
            app.L1mEditField = uieditfield(app.UIFigure, 'numeric');
            app.L1mEditField.Value = 2;
            app.L2mEditFieldLabel = uilabel(app.UIFigure);
            app.L2mEditFieldLabel.Text = 'L2(m)';
            app.L2mEditField = uieditfield(app.UIFigure, 'numeric');
            app.L2mEditField.Value = 3;
            app.M1kgEditFieldLabel = uilabel(app.UIFigure);
            app.M1kgEditFieldLabel.Text = 'M1(kg)';
            app.M1kgEditField = uieditfield(app.UIFigure, 'numeric');
            app.M1kgEditField.Value = 2;
            app.M2kgEditFieldLabel = uilabel(app.UIFigure);
            app.M2kgEditFieldLabel.Text = 'M2(kg)';
            app.M2kgEditField = uieditfield(app.UIFigure, 'numeric');
            app.M2kgEditField.Value = 2;
            app.RunsimulationButton = uibutton(app.UIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'X2 vs Y2')
            xlabel(app.UIAxes, 'x2 (m)')
            ylabel(app.UIAxes, 'y2 (m)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Theta vs time')
            xlabel(app.UIAxes2, 'time(s)')
            ylabel(app.UIAxes2, 'angle (rad)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end